classdef Tarea5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        httpsiopscienceioporgarticle10108813616579aae021Label  matlab.ui.control.Label
        QRSCheckBox                    matlab.ui.control.CheckBox
        AnlisisnolinealdelaVFCLabel    matlab.ui.control.Label
        TablaNoLineal                  matlab.ui.control.Table
        AnlisiseneltiempodelaVFCLabel  matlab.ui.control.Label
        TablaTiempo                    matlab.ui.control.Table
        AnlisisespectraldelaVFCLabel   matlab.ui.control.Label
        TablaFrecuencia                matlab.ui.control.Table
        MtododePSDKnobLabel            matlab.ui.control.Label
        MtododePSDKnob                 matlab.ui.control.DiscreteKnob
        FiltradodelasealListBoxLabel   matlab.ui.control.Label
        FiltradodelasealListBox        matlab.ui.control.ListBox
        FrecuenciamximaSliderLabel     matlab.ui.control.Label
        FrecuenciamximaSlider          matlab.ui.control.Slider
        TiempomximoSliderLabel         matlab.ui.control.Label
        TiempomximoSlider              matlab.ui.control.Slider
        TiempomnimoSliderLabel         matlab.ui.control.Label
        TiempomnimoSlider              matlab.ui.control.Slider
        FrecuenciademuestreoHzEditFieldLabel  matlab.ui.control.Label
        FrecuenciademuestreoHzEditField  matlab.ui.control.NumericEditField
        CargarECGButton                matlab.ui.control.Button
        GrficarButton                  matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        FiltrarButton                  matlab.ui.control.Button
        PoincarePlot                   matlab.ui.control.UIAxes
        ECGTiempo                      matlab.ui.control.UIAxes
        PSDplot                        matlab.ui.control.UIAxes
        IntervalosRR                   matlab.ui.control.UIAxes
        PSDRRplot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ECG % Señal ECG
        t % Tiempo total
        ECG_original % Guardar el ECG original
        tt      % Tiempo para intervalo RR
        rr      % Intervalo RR
        jqrs_ann    % Anotaciones QRS
        tWin        % tiempo ventanas RR
        Activ       % boton de activacion del programa
    end
    
    methods (Access = private)
        
        function Graficar(app)
            cla(app.ECGTiempo)
            plot(app.ECGTiempo,app.t,app.ECG);
            xlim(app.ECGTiempo,[app.TiempomnimoSlider.Value, app.TiempomximoSlider.Value])
            if app.QRSCheckBox.Value
                hold(app.ECGTiempo,"on")
                plot(app.ECGTiempo,app.t(app.jqrs_ann),app.ECG(app.jqrs_ann),'r*');
            end
            
            
            plot(app.IntervalosRR,app.tt,app.rr);
            xlim(app.IntervalosRR,[app.TiempomnimoSlider.Value, app.TiempomximoSlider.Value])

        end
        
        function PSD(app)
            if strcmp(app.MtododePSDKnob.Value,'fft')
                [pxx1,f1] = periodogram(app.ECG,hamming(length(app.ECG)),[],app.FrecuenciademuestreoHzEditField.Value);
            elseif strcmp(app.MtododePSDKnob.Value,'welch')
                [pxx1,f1] = pwelch(app.ECG,[],[],2^nextpow2(length(app.ECG)),app.FrecuenciademuestreoHzEditField.Value);
            elseif strcmp(app.MtododePSDKnob.Value,'burg')
                pb = 100; % pole setting
                [pxx1,f1] = pburg(app.ECG,pb,2^nextpow2(length(app.ECG)),app.FrecuenciademuestreoHzEditField.Value);
            end
            
            plot(app.PSDplot,f1,10*log10(pxx1))
            value = app.FrecuenciamximaSlider.Value;
            if value<2
                value = 2;
            end
            xlim(app.PSDplot,[0, value])
        end 
        
        function CrearRR(app)
            [TT,RR,app.jqrs_ann] = ConvertRawDataToRRIntervals(app);
            [app.rr, app.tt, flagged_beats] = RRIntervalPreprocess(app,RR,TT);
            %app.tWin = CreateWindowRRintervals(app,app.tt, app.rr);
            app.tWin = 0;
        end
        
        function PSD2(app)
            
            out1 = EvalTimeDomainHRVstats(app,app.rr, app.tt, app.tWin);
            CrearTablaTiempo(app,out1)
            
            out2 = EvalFrequencyDomainHRVstats(app,app.rr, app.tt, app.tWin);
            CrearTablaFrecuencia(app,out2)
            
            [SD1, SD2, SD1_SD2_ratio] = EvalPoincareOnWindows(app,app.rr, app.tt, app.tWin);
            [SampEn, ApEn] = EvalEntropyMetrics(app,app.rr, app.tt, 2, 6e-3, app.tWin);

            CrearTablaNoLineal(app,SD1, SD2, SD1_SD2_ratio,SampEn, ApEn)
            
                        
        end 
        
        function CrearTablaFrecuencia(app,out)
            
            %disp(out.vlf)
            n = ["VLF";"LF";"HF"];
            rgbs = [out.vlf out.vlf/out.ttlpwr*100; out.lf out.lf/out.ttlpwr*100; out.hf out.hf/out.ttlpwr*100];
            %disp(rgbs)
            tdata = table(n,rgbs(:,1),rgbs(:,2),'VariableNames',{'Medida','Pot Absoluta ms2','Pot relativa %'});
            %tdata = table(rgbs(:,1),rgbs(:,2));

            app.TablaFrecuencia.Data = tdata;
        end
        
        function CrearTablaTiempo(app,out)
            n = ["Media (ms)";"Mediana (ms)";"Moda (ms)";"Varianza (ms2)";"Skewness";"Kurtosis";"SDNN (ms)";"RMSSD (ms)";"pnna"];
            rgbs = [out.NNmean ; out.NNmedian ; out.NNmode; out.NNvariance ; out.NNskew ; out.NNkurt ; out.SDNN ; out.RMSSD ; out.pnn50];
            tdata = table(n,rgbs,'VariableNames',{'Medida','Valor'});
            app.TablaTiempo.Data = tdata;
        end
        
        function CrearTablaNoLineal(app,SD1, SD2, SD1_SD2_ratio, SampEn, ApEn)
            n = ["SD1 (ms)";"SD2 (ms)";"Ratio SD1/SD2";"SampEn"];
            rgbs = [SD1 ; SD2 ; SD1_SD2_ratio ; SampEn];
            tdata = table(n,rgbs,'VariableNames',{'Medida','Valor'});
            app.TablaNoLineal.Data = tdata;
        end
        
        function [t,rr,jqrs_ann] = ConvertRawDataToRRIntervals(app)
            %   [t,rr,jqrs_ann,sqijs, StartIdxSQIwindows_jw] = ConvertRawDataToRRIntervals(ECG_RawData ,HRVparams, subjectID)  
            %
            %	OVERVIEW:
            %       Load raw signal perfom QRS detection & Signal Quality Index SQI
            %       and extract RR intervals from ECG signal 
            %       (single lead ECG signal)
            %
            %   INPUT:
            %       ECG_RawData : vector containing the 'raw' ECG signal (in mV)  
            %
            %   OUTPUT:
            %       rr                  :  (seconds) Vector containing RR interval
            %       t                   :  (seconds) Time of the rr interval data 
            %
            %   DEPENDENCIES & LIBRARIES:
            %       PhysioNet Cardiovascular Signal Toolbox
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %
            %   REFERENCE: 
            %   Vest et al. "An Open Source Benchmarked HRV Toolbox for Cardiovascular 
            %   Waveform and Interval Analysis" Physiological Measurement (In Press), 2018. 
            %
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox%   ORIGINAL SOURCE AND AUTHORS:     
            %       Written by Giulia Da Poian (giulia.dap@gmail.com) 
            %       Dependent scripts written by various authors 
            %       (see functions for details)       
            %	COPYRIGHT (C) 2018 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
            
%             if nargin < 3
%                 error('Wrong number of arguments in ConvertRawDataToRRIntervals')
%             end
            
            % run_sqrs and wqrsm detectors require ECG in digital values
            % thus ECG data input that is in physical units will multiply the value
            % GainQrsDetect
            
            %GainQrsDetect = 2000; % Default value for gain (adu/physical unit) 
            Fs = app.FrecuenciademuestreoHzEditField.Value; % Frecuencia de muestreo
            ECG_RawData = app.ECG;
            
            if size(ECG_RawData,1)<size(ECG_RawData,2)
                ECG_RawData = ECG_RawData';
            end
            
            ECG_RawData = ECG_RawData(:,1); % If more the one leads use only the first
            
            % QRS Dection 1 - jqrs
            jqrs_ann = run_qrsdet_by_seg(app);
            
            % Translate anotations to rr intervals
            rr = diff(jqrs_ann./Fs);
            t = jqrs_ann(2:end)./Fs;
                        
        end
        
        function [qrs_pos,sign,en_thres] = jqrs(app,ecg)
            % QRS detector based on the P&T method. This is an offline implementation
            % of the detector.
            %
            % inputs
            %   ecg             : one ecg channel on which to run the detector (required)
            %                     in [mV]
            %   HRVparams       : project settings
            %
            % outputs
            %   qrs_pos:        indexes of detected peaks (in samples)
            %   sign:           sign of the peaks (a pos or neg number)
            %   en_thres:       energy threshold used
            %
            %
            %
            % Physionet Challenge 2014, version 1.0
            % Released under the GNU General Public License
            %
            % Copyright (C) 2014  Joachim Behar
            % Oxford university, Intelligent Patient Monitoring Group
            % joachim.behar@gmail.com
            %
            % Last updates:
            %
            % 13-09-2014
            % Joachim Behar
            % - bug on refrac period fixed
            % - code a bit more tidy
            % - condition added on flatline detection for overall segment (if flatline 
            % then returns empty matrices rather than some random stuff)
            %
            % 15-09-2014
            % Julien Oster
            % Sombrero hat instead of band-pass filter as the prefiltering step.
            %
            % 03-03-2017
            % Adriana Vest
            % Changed formatting of input to take a settings struct and changed name of
            % function to improve standardization.
            % Old name: qrs_detect2.m
            % New name: jqrs.m
            %
            % When using this code, please reference the following papers:
            %
            % [1] Behar Joachim, Jonhson Alistair, Clifford Gari D., Oster Julien A
            % Comparison of Single Channel Foetal ECG Extraction Methods. 
            % Annals of Biomedical Engineering. 42(6), 1340-53. 2014
            % 
            % [2] Johnson Alistair E W, Behar Joachim, Oster Julien and Clifford Gari
            % D. R-Peak Estimation using Multimodal Lead Switching. 
            % Accepted for Computing in Cardiology conference 2014.
            %
            %
            % This program is free software; you can redistribute it and/or modify it
            % under the terms of the GNU General Public License as published by the
            % Free Software Foundation; either version 2 of the License, or (at your
            % option) any later version.
            % This program is distributed in the hope that it will be useful, but
            % WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the GNU General
            % Public License for more details.


            % == managing inputs
            REF_PERIOD = 0.250; 
            THRES = 0.6; 
            fs = app.FrecuenciademuestreoHzEditField.Value; % Frecuencia de muestreo 
            fid_vec = [];
            SIGN_FORCE = [];
            debug = 0;
            
            %ecg = app.ECG;
            
            % switch nargin
            %     case 2
            %         REF_PERIOD=varargin{1};
            %     case 3
            %         REF_PERIOD=varargin{1}; 
            %         THRES=varargin{2};
            %     case 4
            %         REF_PERIOD=varargin{1}; 
            %         THRES=varargin{2};
            %         fs=varargin{3};  
            %     case 5
            %         REF_PERIOD=varargin{1}; 
            %         THRES=varargin{2}; 
            %         fs=varargin{3}; 
            %         fid_vec=varargin{4};
            %     case 6
            %         REF_PERIOD=varargin{1}; 
            %         THRES=varargin{2}; 
            %         fs=varargin{3};
            %         fid_vec=varargin{4}; 
            %         SIGN_FORCE=varargin{5};
            %     case 7
            %         REF_PERIOD=varargin{1}; 
            %         THRES=varargin{2}; 
            %         fs=varargin{3};
            %         fid_vec=varargin{4};
            %         SIGN_FORCE=varargin{5};          
            %         debug=varargin{6};
            %     otherwise
            %         error('qrs_detect: wrong number of input arguments \n');
            % end
            
            
            [a b] = size(ecg);
            if(a>b); NB_SAMP=a; elseif(b>a); NB_SAMP=b; ecg=ecg'; end;
            tm = 1/fs:1/fs:ceil(NB_SAMP/fs);
            
            % == constants
            MED_SMOOTH_NB_COEFF = round(fs/100);
            INT_NB_COEFF = round(7*fs/256); % length is 7 for fs=256Hz
            SEARCH_BACK = 1; % perform search back (FIXME: should be in function param)
            MAX_FORCE = []; % if you want to force the energy threshold value (FIXME: should be in function param)
            MIN_AMP = 0.1; % if the median of the filtered ECG is inferior to MINAMP then it is likely to be a flatline
                           % note the importance of the units here for the ECG (mV) 
            NB_SAMP = length(ecg); % number of input samples
            
            try
                % == Bandpass filtering for ECG signal
                % this sombrero hat has shown to give slightly better results than a
                % standard band-pass filter. Plot the frequency response to convince
                % yourself of what it does
                b1 = [-7.757327341237223e-05  -2.357742589814283e-04 -6.689305101192819e-04 -0.001770119249103 ...
                     -0.004364327211358 -0.010013251577232 -0.021344241245400 -0.042182820580118 -0.077080889653194...
                     -0.129740392318591 -0.200064921294891 -0.280328573340852 -0.352139052257134 -0.386867664739069 ...
                         -0.351974030208595 -0.223363323458050 0 0.286427448595213 0.574058766243311 ...
                     0.788100265785590 0.867325070584078 0.788100265785590 0.574058766243311 0.286427448595213 0 ...
                     -0.223363323458050 -0.351974030208595 -0.386867664739069 -0.352139052257134...
                     -0.280328573340852 -0.200064921294891 -0.129740392318591 -0.077080889653194 -0.042182820580118 ...
                     -0.021344241245400 -0.010013251577232 -0.004364327211358 -0.001770119249103 -6.689305101192819e-04...
                     -2.357742589814283e-04 -7.757327341237223e-05];
            
                b1 = resample(b1,fs,250);
                bpfecg = filtfilt(b1,1,ecg)';
                
                if (length(find(abs(bpfecg)>MIN_AMP))/NB_SAMP)>0.20
                    % if 20% of the samples have an absolute amplitude which is higher
                    % than MIN_AMP then we are good to go.
                    
                    % == P&T operations
                    dffecg = diff(bpfecg');  % (4) differentiate (one datum shorter)
                    sqrecg = dffecg.*dffecg; % (5) square ecg
                    intecg = filter(ones(1,INT_NB_COEFF),1,sqrecg); % (6) integrate
                    mdfint = medfilt1(intecg,MED_SMOOTH_NB_COEFF);  % (7) smooth
                    delay  = ceil(INT_NB_COEFF/2); 
                    mdfint = circshift(mdfint,-delay); % remove filter delay for scanning back through ECG
            
                    % look for some measure of signal quality with signal fid_vec? (FIXME)
                    if isempty(fid_vec); mdfintFidel = mdfint; else mdfintFidel(fid_vec>2) = 0; end;
            
                    % == P&T threshold
                    if NB_SAMP/fs>90; xs=sort(mdfintFidel(fs:fs*90)); else xs = sort(mdfintFidel(fs:end)); end;
            
                    if isempty(MAX_FORCE)
                       if NB_SAMP/fs>10
                            ind_xs = ceil(98/100*length(xs)); 
                            en_thres = xs(ind_xs); % if more than ten seconds of ecg then 98% CI
                        else
                            ind_xs = ceil(99/100*length(xs)); 
                            en_thres = xs(ind_xs); % else 99% CI  
                        end 
                    else
                       en_thres = MAX_FORCE;
                    end
            
                    % build an array of segments to look into
                    poss_reg = mdfint>(THRES*en_thres); 
            
                    % in case empty because force threshold and crap in the signal
                    if isempty(poss_reg); poss_reg(10) = 1; end;
            
                    % == P&T QRS detection & search back
                    if SEARCH_BACK
                        indAboveThreshold = find(poss_reg); % ind of samples above threshold
                        RRv = diff(tm(indAboveThreshold));  % compute RRv
                        medRRv = median(RRv(RRv>0.01));
                        indMissedBeat = find(RRv>1.5*medRRv); % missed a peak?
                        % find interval onto which a beat might have been missed
                        indStart = indAboveThreshold(indMissedBeat);
                        indEnd = indAboveThreshold(indMissedBeat+1);
            
                        for i=1:length(indStart)
                            % look for a peak on this interval by lowering the energy threshold
                            poss_reg(indStart(i):indEnd(i)) = mdfint(indStart(i):indEnd(i))>(0.5*THRES*en_thres);
                        end
                    end
            
                    % find indices into boudaries of each segment
                    left  = find(diff([0 poss_reg'])==1);  % remember to zero pad at start
                    right = find(diff([poss_reg' 0])==-1); % remember to zero pad at end
            
                    % looking for max/min?
                    if SIGN_FORCE
                        sign = SIGN_FORCE;
                    else
                        nb_s = length(left<30*fs);
                        loc  = zeros(1,nb_s);
                        for j=1:nb_s
                            [~,loc(j)] = max(abs(bpfecg(left(j):right(j))));
                            loc(j) = loc(j)-1+left(j);
                        end
                        sign = mean(ecg(loc));  % FIXME: change to median?  
                    end
            
                    % loop through all possibilities 
                    compt=1;
                    NB_PEAKS = length(left);
                    maxval = zeros(1,NB_PEAKS);
                    maxloc = zeros(1,NB_PEAKS);
                    for i=1:NB_PEAKS
                        if sign>0
                            % if sign is positive then look for positive peaks
                            [maxval(compt) maxloc(compt)] = max(ecg(left(i):right(i)));
                        else
                            % if sign is negative then look for negative peaks
                            [maxval(compt) maxloc(compt)] = min(ecg(left(i):right(i)));
                        end
                        maxloc(compt) = maxloc(compt)-1+left(i); % add offset of present location
            
                        % refractory period - has proved to improve results
                        if compt>1
                            if maxloc(compt)-maxloc(compt-1)<fs*REF_PERIOD && abs(maxval(compt))<abs(maxval(compt-1))
                                maxloc(compt)=[]; maxval(compt)=[];
                            elseif maxloc(compt)-maxloc(compt-1)<fs*REF_PERIOD && abs(maxval(compt))>=abs(maxval(compt-1))
                                maxloc(compt-1)=[]; maxval(compt-1)=[];
                            else
                                compt=compt+1;
                            end
                        else
                            % if first peak then increment
                            compt=compt+1;
                        end
                    end
            
                    qrs_pos = maxloc; % datapoints QRS positions 
                    R_t = tm(maxloc); % timestamps QRS positions
                    R_amp = maxval; % amplitude at QRS positions
                    hrv = 60./diff(R_t); % heart rate
                else
                    % this is a flat line
                    qrs_pos = [];
                    R_t = [];
                    R_amp = [];
                    hrv = [];
                    sign = [];
                    en_thres = [];
                end
            catch ME
                rethrow(ME);
                for enb=1:length(ME.stack); disp(ME.stack(enb)); end;
                qrs_pos = [1 10 20]; sign = 1; en_thres = 0.5; 
            end
            
            % == plots
            if debug
                figure;
                FONTSIZE = 20;
                ax(1) = subplot(4,1,1); plot(tm,ecg); hold on;plot(tm,bpfecg,'r')
                    title('raw ECG (blue) and zero-pahse FIR filtered ECG (red)'); ylabel('ECG');
                    xlim([0 tm(end)]);  hold off;
                ax(2) = subplot(4,1,2); plot(tm(1:length(mdfint)),mdfint);hold on;
                    plot(tm,max(mdfint)*bpfecg/(2*max(bpfecg)),'r',tm(left),mdfint(left),'og',tm(right),mdfint(right),'om'); 
                    title('Integrated ecg with scan boundaries over scaled ECG');
                    ylabel('Int ECG'); xlim([0 tm(end)]); hold off;
                ax(3) = subplot(4,1,3); plot(tm,bpfecg,'r');hold on;
                    plot(R_t,R_amp,'+k');
                    title('ECG with R-peaks (black) and S-points (green) over ECG')
                    ylabel('ECG+R+S'); xlim([0 tm(end)]); hold off;
                ax(4) = subplot(4,1,4); plot(R_t(1:length(hrv)),hrv,'r+')
                    hold on, title('HR')
                    ylabel('RR (s)'); xlim([0 tm(end)]);
                
                %linkaxes(ax,'x');
                set(gca,'FontSize',FONTSIZE);
                allAxesInFigure = findall(gcf,'type','axes');
                set(allAxesInFigure,'fontSize',FONTSIZE);
            end
            
            
            % NOTES
            %   Finding the P&T energy threshold: in order to avoid crash due to local 
            %   huge bumps, threshold is choosen at 98-99% of amplitude distribution. 
            %   first sec removed for choosing the thres because of filter init lag.
            %   
            %   Search back: look for missed peaks by lowering the threshold in area where the 
            %   RR interval variability (RRv) is higher than 1.5*medianRRv
            % 
            %   Sign of the QRS (signForce): look for the mean sign of the R-peak over the
            %   first 30sec when looking for max of abs value. Then look for the
            %   R-peaks over the whole record that have this given sign. This allows to
            %   not alternate between positive and negative detections which might
            %   happen in some occasion depending on the ECG morphology. It is also
            %   better than forcing to look for a max or min systematically.
                        
        end
       
        function QRS = run_qrsdet_by_seg(app)
            % this function is used to run the QRS detector for each window window (non overlapping)
            % this is needed because in the case of big artefacts at the begining of
            % the record (i.e. where the P&T threshold is computed) it can make the detection fail.
            %
            % inputs
            %   ecg:        ecg signal
            %   fs:         sampling frequency
            %   window:     size of the window onto which to perform QRS detection (in seconds)
            %   thres:      threshold to be used
            %   ecgType:    'FECG' or 'MECG'
            % 
            % output
            %   QRS:        QRS location in nb samples (ms)
            %
            %
            % FECG-ESN toolbox, version 1.0
            % Released under the GNU General Public License
            %
            % Copyright (C) 2014  Joachim Behar
            % Oxford university, Intelligent Patient Monitoring Group
            % joachim.behar@eng.ox.ac.uk
            %
            % Last updated : 28-01-2014
            %
            % This program is free software; you can redistribute it and/or modify it
            % under the terms of the GNU General Public License as published by the
            % Free Software Foundation; either version 2 of the License, or (at your
            % option) any later version.
            % This program is distributed in the hope that it will be useful, but
            % WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the GNU General
            % Public License for more details.
            %
            % 
            % 12-07-2017 Modified by Giulia Da Poian to be used with VOSIM HRV Toolbox
            %            use HRVparsms struct


            % == managing inputs
            
            fs = app.FrecuenciademuestreoHzEditField.Value; % Frecuencia de muestreo 
            window = 15;
            thres = 0.6;
            ecgType = 'MECG';
            ecg = app.ECG;
          
            
            % == general
            segsizeSamp = window*fs; % convert window into nb of samples
            NbSeg = floor(length(ecg)/segsizeSamp); % nb of segments
            QRS = [];
            start = 1;
            stop  = segsizeSamp;
            signForce = 0; % if we want to force the sign of the peak we are looking for
            
            % == core function
            try
                for ch=1:NbSeg
                    % for each segment perform QRS detection
                    QRStemp = 0;
            
                    % take +/-1sec around selected subsegment exept for the borders. This
                    % is in case there is a QRS in between segments -> allows to locate
                    % them well.
                    if ch==1
                        % first subsegment
                        dTplus  = fs;
                        dTminus = 0;
                    elseif ch==NbSeg
                        % last subsegment
                        dTplus  = 0;
                        dTminus = fs;    
                    else
                        % any other subsegment
                        dTplus  = fs;
                        dTminus = fs;  
                    end
            
                    % lowering the threshold in case not enough beats are
                    % detected. Also changed the refractory period to be different between
                    % mother and foetus. sign of peaks is determined by the sign on the
                    % first window and then is forced for the following windows.
                    if strcmp(ecgType,'FECG')
                        thresTrans = thres;
                        while length(QRStemp)<20 && thresTrans>0.1
                            [QRStemp,signForce] = jqrs(app,ecg(start-dTminus:stop+dTplus));
                            thresTrans = thresTrans-0.1;
                        end
                    else
                        [QRStemp,signForce] = jqrs(app,ecg(start-dTminus:stop+dTplus));
                    end
            
                    NewQRS = (start-1)-dTminus+QRStemp;
                    NewQRS(NewQRS>stop) = [];
                    NewQRS(NewQRS<start) = [];
            
                    if ~isempty(QRS) && ~isempty(NewQRS) && NewQRS(1)-QRS(end)<0.25*fs
                        % this is needed to avoid double detection at the transition point
                        % between two windows
                        NewQRS(1) = [];
                    end
                    QRS = [QRS NewQRS];
            
                    start = start+segsizeSamp;
                    stop = stop+segsizeSamp;
                end
            
            catch ME
                for enb=1:length(ME.stack); disp(ME.stack(enb)); end;
                QRS = [1000 2000];
                rethrow(ME);
            end
            
        end
                        
        function [cleanNN, cleantNN, flagged_beats] = RRIntervalPreprocess(app,rr,t_rr)
            %
            %   [cleanNN, cleantNN, flagged_beats] = RRIntervalPreprocess(rr, time, annotations, HRVparams)
            %
            %   OVERVIEW:   This function preprocesses RR interval data in preparation
            %               for running HRV analyses. Noise and non-normal beats are
            %               removed from the RR interval data and replaced with
            %               interpolated data using a interpolation method of choice.
            %
            %   INPUT:      rr          - a single row of rr interval data in seconds
            %               t_rr        - time of the rr interval data 
            %                             (seconds)
            %               annotations - (optional) annotations of the RR data at each
            %                              point indicating the quality of the beat
            %               HRVparams   - struct of settings for hrv_toolbox analysis
            %
            %   OUTPUT:     cleanNN       - normal normal interval data
            %               cleantNN      - time of NN interval
            %               flagged_beats - percent of original data removed
            %   REFERENCE: 
            %       Clifford, G. (2002). "Characterizing Artefact in the Normal 
            %       Human 24-Hour RR Time Series to Aid Identification and Artificial 
            %       Replication of Circadian Variations in Human Beat to Beat Heart
            %       Rate using a Simple Threshold."
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   ORIGINAL SOURCE AND AUTHORS:     
            %       Script written by Adriana N. Vest
            %       Dependent scripts written by various authors 
            %       (see functions for details)    
            %
            %   09-013-2017
            %   Edited by Giulia Da Poian
            %   -Updated the method for measuring changes in the current RR interval 
            %   from the last N (N=5) intervals and excluding intervals that 
            %   change by more than a certain percentage define by 
            %   HRVparams.preprocess.per_limit
            %   - Removed SQI, windows containing low quality SQI segments are removed  
            %     in EvalTimeDomainHRVstats and EvalFrequencyDomainHRVstats
            %
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %
            
            
            % CINC 2002 - Cite for rationale for % good data for cutoff
            
            % 2004 paper - var goes up with numbers of beats dropped out
            % don't put phantom beats in for the lomb
            
            Fs = app.FrecuenciademuestreoHzEditField.Value;
            
            % check input
            if isempty(t_rr)
                t_rr = cumsum(rr);
            end
            
            %if isempty(annotations)
                annotations = repmat('N',[length(rr) 1]);
            %end
            
%             if nargin < 4
%             	error('Not enough input arguments!')
%             end
            
            figures = 0;
            
            % 1. Identify data that is too close together and Remove
            % These are not counted towards the total signal removed
            idx_remove = find(diff(t_rr) < 1/Fs); 
            % could make this the refractory period - and have the variable in the settings
            % document
            rr(idx_remove+1) = [];
            t_rr(idx_remove) = [];
            annotations(idx_remove) = [];
            clear idx_remove;
            
            % 2. Find Artifact Annotations and Remove Those Points
            % These are not counted towards the total signal removed
            idx_remove = strcmp('|', annotations); % find artifacts
            rr(idx_remove) = [];
            annotations(idx_remove) = [];
            t_rr(idx_remove) = [];
            
            idx_remove2 = strcmp('~', annotations); % find artifacts
            rr(idx_remove2) = [];
            annotations(idx_remove2) = [];
            t_rr(idx_remove2) = [];
            clear idx_remove idx_remove2
            
            % 3. Remove Large Gaps in Data At Beginning of Dataset
            %if t(1) > settings.preprocess.forward_gap
            %    t = t - t(1);
            %end
            
            % 4. Remove Large RR intervals Caused by Gaps
            % These are not counted towards the total signal removed
            idx_remove = find(rr >= 2);
            rr(idx_remove) = [];
            t_rr(idx_remove) = [];
            annotations(idx_remove) = [];
            clear idx_remove;
            
            % Detect PVCs and Implement Phantom Beat
            % Look for premature ectopic beats
            % replace value of ectopic beat with a linear interpolated point
            % replace sample number
            % now next beat becomes normal
            % with more than two Vent ectopic beats, cut off data.
            % pvcthresh = .3;
            % rrdiff = diff(rr);
            % idx = find(rrdiff > pvcthresh);
            % for i = 1:length(idx)
            %     ppvc = rr(idx(i)+1);
            %     if rr(idx(i)+1+1) > rr(idx(i)+1)
            %         if (rr(idx(i)+1+2) - rr(idx(i))) < pvcthresh
            %             % If all is true, we can assume we have a PVC
            %             gap = rr(idx(i)+1+2)-rr(idx(i)+1-1);
            %             even = gap/3;
            %             rr(i) = NaN;
            %             rr(i+1) = NaN;
            %             avgrr = mean(rr(i-10:i-1));
            %             rr(i) = even + rr(i-1);
            %             rr(i+2) = even + rr(i);
            %         end
            %     end
            % end
            
            %hold on; plot(t,rr);
            
            % 5. Find Non-'N' Annotations
            [~,~,ann_outliers] = AnnotationConversion(app,annotations);
            if ~(length(annotations) == length(rr))
                ann_outliers = zeros(length(rr),1);
            end
            
            
            % 6. Find RR Over Given Percentage Change 
            perLimit = 0.2;
            idxRRtoBeRemoved = FindSpikesInRR(app,rr, perLimit); 
            
            % 7. Find Long Running Outliers


            % 8. Combine Annotations and Percentage Outliers
            % Combination of methods
            outliers_combined = idxRRtoBeRemoved(:) + ann_outliers(:);
            outliers = logical(outliers_combined); 
            
            % remove extra points that may have been introduced at the end of the file
            if (length(outliers) > length(ann_outliers)) 
                fprintf('Too many Outliers - Removing last Point\n');
                z = length(outliers);
                outliers(z) = [];
            end
            
            % timestamp = [find(diff([-1; outliers; -1]) ~= 0)]; % where does outliers change?
            % runlength = diff(timestamp); % how long is each segment
            % % to split them for 0's and 1's
            % runlength0 = runlength(1+(outliers(1)==1):2:end);
            % runlength1 = runlength(1+(outliers(1)==0):2:end);
            % 
            % r0_rmv_small = (find(runlength0 > 10));
            % r1_rmv_small = (find(runlength1 > 10));
            % idx_groups_outliers =  find(runlength1 > 10);
            
            % 9. Remove or Interpolate Outliers
            idx_outliers = find(outliers == 1);
            
            % Keep count of outliers 
            numOutliers = length(idx_outliers);
            
            rr_original = rr;
            rr(idx_outliers) = NaN;
            
            switch 'rem'
                case 'cub'
                    NN_Outliers = interp1(t_rr,rr,t_rr,'spline','extrap');
                    t_Outliers = t_rr;
                case 'pchip'
                    NN_Outliers = interp1(t_rr,rr,t_rr,'pchip');
                    t_Outliers = t_rr;
                case 'lin'
                    NN_Outliers = interp1(t_rr,rr,t_rr,'linear','extrap'); 
                    t_Outliers = t_rr;
                case 'rem'
                    NN_Outliers = rr;
                    NN_Outliers(idx_outliers) = [];
                    t_Outliers = t_rr;
                    t_Outliers(idx_outliers) = []; 
                otherwise % By default remove outliers
                    NN_Outliers = rr;
                    NN_Outliers(idx_outliers) = [];
                    t_Outliers = t_rr;
                    t_Outliers(idx_outliers) = [];
            end
            
            if figures
                figure;
                plot(t_rr,rr_original,t_Outliers,NN_Outliers);
                legend('raw','interp1(after outliers removed)')
            end
            
            % 10. Identify Non-physiologic Beats
            toohigh = NN_Outliers > 60/30;    % equivalent to RR = 2
            toolow = NN_Outliers < 60/160;     % equivalent to RR = .375
            
            idx_toolow = find(toolow == 1);
            NN_NonPhysBeats = NN_Outliers;
            NN_NonPhysBeats(idx_toolow) = NaN;
            numOutliers = numOutliers + length(idx_toolow);
            
            
            switch 'rem'
                case 'cub'
                    NN_NonPhysBeats = interp1(t_Outliers,NN_NonPhysBeats,t_Outliers,'spline','extrap');
                    t_NonPhysBeats = t_Outliers;
                    flagged_beats = logical(outliers(:) + toohigh(:)+ toolow(:));
                case 'pchip'
                    NN_NonPhysBeats = interp1(t_Outliers,NN_NonPhysBeats,t_Outliers,'pchip');
                    t_NonPhysBeats = t_Outliers;
                    flagged_beats = logical(outliers(:) + toohigh(:)+ toolow(:));
                case 'lin'
                    NN_NonPhysBeats = interp1(t_Outliers,NN_NonPhysBeats,t_Outliers,'linear','extrap'); 
                    t_NonPhysBeats = t_Outliers;
                    flagged_beats = logical(outliers(:) + toohigh(:)+ toolow(:));
                case 'rem'
                    NN_NonPhysBeats(idx_toolow) = [];
                    t_NonPhysBeats = t_Outliers;
                    t_NonPhysBeats(idx_toolow) = []; % Review this line of code for improvement
                otherwise % use cubic spline interpoletion as default
                    NN_NonPhysBeats = interp1(t_Outliers,NN_NonPhysBeats,t_Outliers,'pchip');
                    t_NonPhysBeats = t_Outliers;
            end
            
            if figures
                hold on;
                plot(t_NonPhysBeats,NN_NonPhysBeats+.01);
                hold on; plot(t_NonPhysBeats,toolow,'o')
                legend('raw','interp1(after outliers removed)',...
                    'interp2(after too low)','toolow')
            end


            % 11. Interpolate Through Beats that are Too Fast
            toohigh = NN_NonPhysBeats > 60/30;    % equivalent to RR = 2
            
            idx_outliers_2ndPass = find(logical(toohigh(:)) ~= 0);
            NN_TooFastBeats = NN_NonPhysBeats;
            NN_TooFastBeats(idx_outliers_2ndPass) = NaN;
            numOutliers = numOutliers + length(idx_outliers_2ndPass);
            if strcmp('rem','rem')
                flagged_beats = numOutliers;
            end
            
            switch 'rem'
                case 'cub'            
                    NN_TooFastBeats = interp1(t_NonPhysBeats,NN_TooFastBeats,t_NonPhysBeats,'spline','extrap');
                    t_TooFasyBeats = t_NonPhysBeats;
                case 'pchip'
                    NN_TooFastBeats = interp1(t_NonPhysBeats,NN_TooFastBeats,t_NonPhysBeats,'pchip');
                    t_TooFasyBeats = t_NonPhysBeats;
                case 'lin'
                    NN_TooFastBeats = interp1(t_NonPhysBeats,NN_TooFastBeats,t_NonPhysBeats,'linear','extrap');
                    t_TooFasyBeats = t_NonPhysBeats;
                case 'rem'
                    NN_TooFastBeats(idx_outliers_2ndPass) = [];
                    t_TooFasyBeats = t_NonPhysBeats;
                    t_TooFasyBeats(idx_outliers_2ndPass) = []; % Review this line of code for improvement
                otherwise % USe cubic spline interpoletion as default
                    NN_TooFastBeats = interp1(t_NonPhysBeats,NN_TooFastBeats,t_NonPhysBeats,'spline','extrap');
                    t_TooFasyBeats = t_NonPhysBeats;
            end
            
            if figures
                hold on;
                plot(t_TooFasyBeats,NN_TooFastBeats);
                legend('raw','interp1(after outliers removed)',...
                    'interp2(after too low)','toolow','interp3 (after too fast removed)')
            end
            
            % 12. Remove erroneous data at the end of a record 
            %       (i.e. a un-physiologic point caused by removing data at the end of
            %       a record)
            
            while NN_TooFastBeats(end) > 60/30	% equivalent to RR = 2
                NN_TooFastBeats(end) = [];
                t_TooFasyBeats(end) = [];
            end
            
            
            cleanNN = NN_TooFastBeats;
            cleantNN = t_TooFasyBeats;
        end

        function idxRRtoBeRemoved = FindSpikesInRR(app,RR, th)
            % 
            % idxRRtoBeRemoved = FindSpikesInRR(RR, th)
            %
            % OVERVIEW : Code used to clean RR intervals that change more than a given 
            % threshold (eg., th = 0.2 = 20%) with respect to the median value of the 
            % previous 5 and next 5 RR intervals (using a forward-backward approach).
            %
            % INPUTS:
            %       RR : a single row of rr interval data in seconds
            %       th : threshold percent limit of change from one interval to the next
            % OUTPUTS:
            %       idxRRtoBeRemoved : a single vector of indexes related to RR
            %                          intervals corresponding to a change > th
            %
            %   DEPENDENCIES & LIBRARIES:
            %       PhysioNet Cardiovascular Signal Toolbox
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %
            %   REFERENCE: 
            %       Vest et al. "An Open Source Benchmarked HRV Toolbox for Cardiovascular 
            %       Waveform and Interval Analysis" Physiological Measurement (In Press), 2018. 
            %
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %
            %   Written by Giulia Da Poian (giulia.dap@gmail.com), 09-13-2017
            %
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %
            
            
            if size(RR,1)>size(RR,2)
                RR = RR';
            end
            
            % Forward search 
            FiveRR_MedianVal = medfilt1(RR,5); % compute as median RR(-i-2: i+2)
            % shift of three position to aligne with to corresponding RR 
            FiveRR_MedianVal = [RR(1:5) FiveRR_MedianVal(3:end-3)];
            rr_above_th = find((abs(RR-FiveRR_MedianVal)./FiveRR_MedianVal)>=th);
            
            RR_forward = RR;
            RR_forward(rr_above_th) = NaN;
            
            
            % Backward search 
            RRfilpped = fliplr(RR);
            
            FiveRR_MedianVal = medfilt1(RRfilpped,5); % compute as median RR(-i-2: i+2)
            % shift of three position to aligne with to corresponding RR 
            FiveRR_MedianVal = [RRfilpped(1:5) FiveRR_MedianVal(3:end-3)];
            rr_above_th = find(abs(RRfilpped-FiveRR_MedianVal)./FiveRR_MedianVal>=th);
            rr_above_th = sort(length(RR)-rr_above_th+1);
            
            
            RR_backward = RRfilpped;
            RR_backward(rr_above_th) = NaN;
            
            
            % Combine 
            
            idxRRtoBeRemoved = (isnan(RR_forward) & isnan(RR_backward));
            
            
        end
        
        function [goodbeats,badbeats,outliers] = AnnotationConversion(app,annotations)
            % [goodbeats,badbeats,outliers] = AnnotationConversion(annotations)
            %
            %   OVERVIEW:   Convert annotations to a logical array, with all Normal
            %               beats (labeled "N") returning as 1 and abnormal beats (not
            %               labeled "N") returning as 0.
            %
            %   INPUT:      annotations - a charactor array containing the annotations
            %               of heart beats, generated by GE MARS and then read by
            %               read_qrs.m
            %
            %   OUTPUT:     all outputs are returned in cell array format
            %               goodbeats - "N" beats
            %               badbeats  - the logical inverse of goodbeats
            %               outliers  - an array of points to be discarded when
            %                           pre-processing the RR interval data. 
            %                           This includes not only  bad beats, but the
            %                           beats immediately following bad beats
            %
            %   DEPENDENCIES & LIBRARIES:
            %       HRV_toolbox https://github.com/cliffordlab/hrv_toolbox
            %       WFDB Matlab toolbox https://github.com/ikarosilva/wfdb-app-toolbox
            %       WFDB Toolbox https://physionet.org/physiotools/wfdb.shtml
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   ORIGINAL SOURCE AND AUTHORS:     
            %       Main script written by Adriana N. Vest
            %       Dependent scripts written by various authors 
            %       (see functions for details)       
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %%
            goodbeats= strcmp(cellstr(annotations),'N');   % find good beats
            badbeats = ~goodbeats;                 % badbeats is logical inverse of  
                                                   % good beats
            outliers = false(length(badbeats),1);  % initialize vector to be use to 
                                                   % eliminate bad beats
            
            % removing beats after non-N beats 
            for i = 1:length(badbeats)
                if badbeats(i)
                    outliers(i) = 1;
                    outliers(i+1) = 1;
                end
                    
            end
            
            % remove extra points that may have been introduced at the end of the file
            if (length(outliers) > length(badbeats)) 
                z = length(outliers);
                outliers(z) = [];
            end

    end % end function
        
        function windowRRintervals = CreateWindowRRintervals(app,tNN, NN)
            %
            % windowRRintervals = CreateWindowRRintervals(NN, tNN, settings, options)
            %   
            %   OVERVIEW:   This function returns the starting time (in seconds) of 
            %               each window to be analyzed.
            %
            %   INPUT:      tNN       : (seconds) a single row of time of the rr interval 
            %               NN        : (seconds) a single row of NN (normal normal) interval
            %                           data
            %               HRVparams : struct of settings for hrv_toolbox analysis
            %               option    : 'normal', 'af', 'sqi', 'mse', 'dfa'
            %               
            %   OUTPUT:     windowRRintervals : array containing the starting time 
            %                                   (in seconds) of each window to be
            %                                   analyzed
            %                                   
            %
            %   DEPENDENCIES & LIBRARIES:
            %       PhysioNet Cardiovascular Signal Toolbox
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %
            %   REFERENCE: 
            %   Vest et al. "An Open Source Benchmarked HRV Toolbox for Cardiovascular 
            %   Waveform and Interval Analysis" Physiological Measurement (In Press), 2018. 
            %
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   ORIGINAL SOURCE AND AUTHORS:     
            %       Main script written by Adriana N. Vest
            %       Dependent scripts written by various authors 
            %       (see functions for details)   
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %
            
            % Verify input arguments
            
%             if nargin< 1
%                 error('Need to supply time to create windows')
%             end
%             if nargin<4 || isempty(HRVparams) 
%                  option = 'normal';
%             end
            
            % Set Defaults
            option = 'normal';
            increment = 30;
            windowlength = 300;
            win_tol = .15;
            
%             switch option
%                 case 'af'
%                     increment = HRVparams.af.increment;
%                     windowlength = HRVparams.af.windowlength;
%                 case 'mse'
%                     increment = HRVparams.MSE.increment * 3600;
%                     windowlength = HRVparams.MSE.windowlength * 3600;
%                     if isempty(increment)
%                         windowRRintervals = 0;
%                         return % no need to crate windows , use entair signal
%                     end 
%                 case 'dfa'
%                     increment = HRVparams.DFA.increment * 3600;
%                     windowlength = HRVparams.DFA.windowlength * 3600;
%                     if isempty(increment)
%                         windowRRintervals = 0;
%                         return  % no need to crate windows , use entair signal
%                     end       
%                 case 'sqi'
%                     increment = HRVparams.sqi.increment;
%                     windowlength = HRVparams.sqi.windowlength;
%                 case 'HRT'
%                     increment = HRVparams.HRT.increment;
%                     windowlength = HRVparams.HRT.windowlength * 3600;
%                     if windowlength > tNN(end) 
%                         windowRRintervals = 0;
%                         return;
%                     end
%                         
%             end
            
            nx = floor(tNN(end));               % length of sequence
            overlap = windowlength-increment;   % number of overlapping elements
            Nwinds = fix((nx-overlap)/(windowlength-overlap));    % number of sliding windows
            % Initialize output matrix
            windowRRintervals = (0:(Nwinds-1))*(windowlength-overlap);  % starting index of each windows
            
            % Initialize loop variables
            t_window_start = 0;     % Window Start Time
            i = 1;                       % Counter
            
            % for j = 1:(floor(tNN(end))-(windowlength))/increment
            %     indicies = [];
            %     try
            %         indicies = find(tNN >= (j-1)*increment & tNN < windowlength+(j-1)*increment);
            %         % windows_all: first coloumn is start time of sliding window
            %         % and second column is end time of sliding window
            %         windows_all(j,1) = tNN(indicies(1));
            %         windows_all(j,2) = tNN(indicies(end));
            %     catch
            %         windows_all(j,1) = NaN;
            %         windows_all(j,2) = NaN;
            %     end
            % end
            
            if ~strcmp(option,'af') && ~strcmp(option,'sqi')
                
                while t_window_start <= tNN(end) - windowlength + increment
            
                    % Find indices of time values in this segment
                    t_win = tNN(tNN >= t_window_start & tNN < t_window_start + windowlength);
            
                    % if NN intervals are supplied, assign them to the current window
                    % if not, put in place a vector of ones as a place holder
                    if ~isempty(NN)
                        nn_win = NN(tNN >= t_window_start & tNN < t_window_start + windowlength);
                    else
                        nn_win = (windowlength/length(t_win))* ones(length(t_win),1);
                    end
            
                    % Store the begin time of window
                    windowRRintervals(i) = t_window_start;
            
                    % Increment time by sliding segment length (sec)
                    t_window_start = t_window_start + increment;
            
                    % Check Actual Window Length and mark windows that do not meet the
                    % crieria for Adequate Window Length
                    % First remove unphysiologic beats from candidates for this
                    % measurement:
                    idxhi = find(nn_win > 60/30);
                    idxlo = find(nn_win < 60/160);
                    comb = [idxhi(:) ; idxlo(:)];
                    nn_win(comb) = [];
                    % Now query the true length of the window by adding up all of the NN
                    % intervals
                    truelength = sum(nn_win(:));
                    if truelength < (windowlength * (1-win_tol))
                            windowRRintervals(i) = NaN; 
                    end
            
                    % Increment loop index
                    i = i + 1;
                end
            end

        end % end of function
        
        function rr_int = ApplyResamplingToRR(app,t_win,rr_0)
            %
            %   rr_int = ApplyResamplingToRR(t_win,rr_0,HRVparams)
            %	
            %   OVERVIEW: Resampling of RR intervals before to apply methods that  
            %             require resampled data for frequency domain analysis
            %
            %   INPUT:
            %       t_win        - Vector containing start time of each window
            %                     or ECG/PPG waveform  
            %       rr_0         - vector of rr intervals to be resampled
            %       HRVparams   - struct of settings for hrv_toolbox analysis that can
            %                     be obtained using InitializeHRVparams.m function 
            %                     HRVparams = InitializeHRVparams();
            %   OUTPUT 
            %       rr_int      - resampled rr intervals
            %
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   ORIGINAL SOURCE AND AUTHORS:     
            %       This script written by Giulia Da Poian
            %       Dependent scripts written by various authors 
            %       (see functions for details)       
            %	COPYRIGHT (C) 2018 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            
%             rr_0 = app.rr;
%             t_win = app.tt;
            
            sf = 2; % (Hz) resampling frequency
            ti = t_win(1):1/sf:t_win(end);       % time values for interp.
            interp_method = 'cub';
            
            % Chose the resampling method to use
            switch interp_method 
                case 'cub'
                    rr_int = interp1(t_win,rr_0,ti','spline')'; % cubic spline interpolation
                case 'lin'
                    rr_int = interp1(t_win,rr_0,ti','linear')'; %linear interpolation
                otherwise
                    warning('using cubic spline method')
                    rr_int = interp1(t_win,rr_0,ti','spline')'; % cubic spline interpolation
            end
            
                    
        end
         
        function out = EvalFrequencyDomainHRVstats(app,NN, tNN, tWin)
            %
            % out = EvalFrequencyDomainHRVstats (NN, tNN, , sqi, settings)
            %   
            %   OVERVIEW:   This function returns frequency domain HRV metrics 
            %               calculated on input NN intervals.
            %
            %   INPUT:      MANDATORY:
            %               NN          : a single row of NN (normal normal) interval
            %                             data in seconds
            %               tNN         : a single row of time of the rr interval 
            %                             data (seconds)
            %               sqi         : (Optional) Signal Quality Index; Requires a 
            %                             matrix with at least two columns. Column 1 
            %                             should be timestamps of each sqi measure, and 
            %                             Column 2 should be SQI on a scale from 0 to 1.
            %                             Additional columns can be included with
            %                             additional sqi at the same timestamps
            %               HRVparams   : struct of settings for hrv_toolbox analysis
            %               tWin .      : vector containing the starting time of each
            %                             windows (in seconds) 
            %
            %   OUTPUT:     out.ulf        : (ms^2) Power in the ultra low frequency range (default < 0.003 Hz)
            %               out.vlf        : (ms^2) Power in very low frequency range (default 0.003 <= vlf < 0.04 Hz)
            %               out.lf         : (ms^2) Power in low frequency range (default 0.04Hz  <= lf < 0.15 Hz)
            %               out.hf         : (ms^2) Power in high frequency range (default 0.15 <= hf < 0.4 Hz)
            %               out.lfhf       : Ratio LF [ms^2]/HF [ms^2]
            %               out.ttlpwr     : (ms^2) Total spectral power (approximately <0.4 Hz)
            %               out.fdflag     : 1 - Lomb Periodogram or other method failed
            %                                2 - Not enough high SQI data
            %                                3 - Not enough data in the window to analyze
            %                                4 - Window is missing too much data
            %                                5 - Success
            %
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   ORIGINAL SOURCE AND AUTHORS:     
            %       Gari Clifford HRV Tools
            %                   G. Clifford 2001 gari@mit.edu, calc_lfhf.m 
            %                   http://www.robots.ox.ac.uk/~gari/CODE/HRV/
            %       ChengYu Lui
            %       Adriana Vest
            %       Dependent scripts written by various authors 
            %       (see functions for details)       
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information  
            %
            %   10-28-2017 Modified by Giulia Da Poian: 
            %   Initlialiezed output as NaN
            %   Removed fft, Welch and Burg method without resampling. 
            %   Added function for resampling when required by the method 
            %   Acceppting only one method at time to make the function output more
            %   clear
            %   Removed option to write results on file directly from this function
            %   Converted output to ms^2


            % Verify input arguments
            
%             if nargin < 5
%                 error('Eval_FrequencydomainHRVstats: wrong number of input arguments!')
%             end
            
%             if isempty(sqi) 
                 sqi(:,1) = tNN;
                 sqi(:,2) = ones(length(tNN),1);
%             end
            
            ULF = [0 .0033];                    % Requires window > 300 s
            VLF = [0.0033 .04];                 % Requires at least 300 s window
            LF = [.04 .15];                     % Requires at least 25 s window
            HF = [0.15 0.4];                    % Requires at least 7 s window
            
            % Set Defaults
            %windowlength = 300;
            windowlength = max(app.t);       
            fd_threshold1 = 0.9;
            fd_threshold2 = .20;
            limits = [ULF; VLF; LF; HF];
            %method = 'burg';           % Options: 'lomb' (no), 'burg', 'fft', 'welch' 
            method = app.MtododePSDKnob.Value;
            plot_on = 1;
            debug_sine = 0;   % if use the sine wave to debug
            %f_sine = 0.15;       % the frequency of the added sine wave
            %weight = 0.03;
            sf = 2;
            zero_mean = 1;
            
            
            % Preallocate arrays before entering the loop
            
            out.ulf = nan(1,length(tWin));
            out.vlf = nan(1,length(tWin));
            out.lf = nan(1,length(tWin));
            out.hf = nan(1,length(tWin));
            out.lfhf = nan(1,length(tWin));
            out.ttlpwr = nan(1,length(tWin));
            out.fdflag = nan(1,length(tWin));
            % Window by Window Analysis
            
            % Loop through each window of RR data
            for iWin = 1:length(tWin)
                % Check window for sufficient data
                if ~isnan(tWin(iWin))    
                    % Isolate data in this window
                    idx_NN_in_win = find(tNN >= tWin(iWin) & tNN < tWin(iWin) + windowlength);
                    idx_sqi_win = find(sqi(:,1) >= tWin(iWin) & sqi(:,1) < tWin(iWin) + windowlength);
            
                    sqi_win = sqi(idx_sqi_win,:);
                    t_win = tNN(idx_NN_in_win);
                    nn_win = NN(idx_NN_in_win);
            
                    % Analysis of SQI for the window
                    lowqual_idx = find(sqi_win(:,2) < fd_threshold1);
            
                    % If enough data has an adequate SQI, perform the calculations
                    if numel(lowqual_idx)/length(sqi_win(:,2)) < fd_threshold2
            
                        % Initialize variables
                        % maxF=fs/2; % This calculation works for regularly sampled data
                        N     =  length(nn_win);       % RR interval series length
                        % m_fs  = 1/mean(nn_win);     % mean frequency of heart rate, i.e., the mean sample rate (fs) of RR sereis  
                        % max_f = .5/(min(nn_win));   % max frequency of RR interval series
                        % nfft  = 2^nextpow2(N);      % Next power of 2 from N
                        nfft = 1024;
                        %F = [1/nfft:1/nfft:m_fs];  % setting up frequency vector
                        F = [1/nfft:1/nfft:.5];  % setting up frequency vector
            
                        % add sine wave to RR signal
                        if debug_sine
                            s_sin  = weight*sin(2*pi*f_sine*t);
                            nn_win = nn_win + s_sin;
                        end
            
                        % subtract mean of segment
                        if zero_mean
                            rr_0 = nn_win - mean(nn_win); %rudimentary detrending
                        else
                            rr_0 = nn_win;
                        end
                        
                        switch method
                            % 1. Lomb-Scargle Periodogram (no resampling)
%                             case 'lomb'
%                                 try
%                                     [PSDlomb,Flomb] = CalcLomb(t_win,rr_0,F,nfft,HRVparams.freq.normalize_lomb);
%                                     % plomb equivalent to CalcLomb when normalized 
%                                     % lomb-scargle periodogram
%                                     %[PSDmatlablomb,fplombout] = plomb(vv,tt,F,'normalized');
%                                     [out.ulf(iWin), out.vlf(iWin), out.lf(iWin), out.hf(iWin), out.lfhf(iWin),...
%                                         out.ttlpwr(iWin)] = CalcLfHfParams(app,PSDlomb, Flomb, limits, plot_on);
%                                     
%                                     out.fdflag(iWin) = 5; %'sucess';
%             
%                                 catch
%                                     out.fdflag(iWin) = 1; %'lomb_failed'
%                                 end
            
                            % 2. Pwelch (after resampling)
                            case 'welch'                    
                                % Resampling
                                rr_int = ApplyResamplingToRR(app,t_win,rr_0);
                                try
                                    [PSDwelch,Fwelch] = pwelch(rr_int,[],[],2^nextpow2(length(rr_int)),sf);
                                    [out.ulf(iWin), out.vlf(iWin), out.lf(iWin), out.hf(iWin), out.lfhf(iWin),...
                                            out.ttlpwr(iWin)] = CalcLfHfParams(app,PSDwelch, Fwelch, limits, plot_on) ;  
                                    out.fdflag(iWin) = 5; %'sucess';
                                catch
                                    out.fdflag(iWin) = 1; %'Pwelch faild';
                                end
                                
                            % 3. FFT (after resampling)
                            case 'fft'
                                % Resampling
                                rr_int = ApplyResamplingToRR(app,t_win,rr_0);
                                try
                                    Y_FFT2 = fft(rr_int);  % fft using the original sample length
                                    PSDfft = Y_FFT2.*conj(Y_FFT2)/length(rr_int);
                                    Ffft = sf*(0:floor(length(rr_int)/2)+1)/length(rr_int);
                                    [out.ulf(iWin), out.vlf(iWin), out.lf(iWin), out.hf(iWin), out.lfhf(iWin),...
                                            out.ttlpwr(iWin)] = CalcLfHfParams(app,PSDfft(1:length(Ffft)), Ffft, limits, plot_on);
                                    out.fdflag(iWin) = 5; %'sucess';
                                catch
                                    out.fdflag(iWin) = 1; %'fft faild';
                                end
            
                            % 4. Burg (after resampling)
                            case 'burg'
                                % Resampling
                                rr_int = ApplyResamplingToRR(app,t_win,rr_0);
                                try
                                % ChengYu's Burg Method with resampled data
                                pb = 100; % pole setting
                                [PSD_Burg1,f_Burg1] = pburg(rr_int,pb,2^nextpow2(length(rr_int)),sf);
                                [out.ulf(iWin), out.vlf(iWin), out.lf(iWin), out.hf(iWin), out.lfhf(iWin),...
                                        out.ttlpwr(iWin)] = CalcLfHfParams(app,PSD_Burg1,f_Burg1, limits, plot_on);
                                    out.fdflag(iWin) = 5; %'sucess';
                                catch
                                    out.fdflag(iWin) = 1; %'Burg faild';
                                end
                        end
                    else
                        out.fdflag(iWin) = 2; %'nt_enuf_hi_sqi_data'
                    end % end conditional statements that run only if SQI is adequate
            
                else
                    out.fdflag(iWin) = 3; %'nt_enuf_data_in_win';   
                end % end check for sufficient data
            end % end of loop through windows
        end
   
        function [ulf, vlf, lf, hf, lfhf, ttlpwr] = CalcLfHfParams(app,PSD, F, limits,plot_on)
            % [ulf, vlf, lf, hf, lfhf, ttlpwr] = CalcLfHfParams(PSD, F, limits,plot_on)
            %
            %   OVERVIEW: Compute the frequency domain features for a given PSD and
            %             frequency bans limits
            %         
            %   INPUT:      
            %        PSD     - power spectral density 
            %        F       - frequency vector
            %        limits  - frequency domain analysis limits
            %        plot_on - 
            %
            %   OUTPUT:     
            %	- ulf     : (ms^2) Power in the ultra low frequency range (default < 0.003 Hz)
            %	- vlf     : (ms^2) Power in very low frequency range (default 0.003 <= vlf < 0.04 Hz)
            %	- lf      : (ms^2) Power in low frequency range (default 0.04Hz  <= lf < 0.15 Hz)
            %	- hf      : (ms^2) Power in high frequency range (default 0.15 <= hf < 0.4 Hz)
            %	- lfhf    : Ratio LF [ms^2]/HF [ms^2]
            %	- ttlpwr  : (ms^2) Total spectral power (approximately <0.4 Hz)
            %     
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   ORIGINAL SOURCE AND AUTHORS:     
            %       Main script written by Adriana N. Vest
            %       Dependent scripts written by various authors 
            %       (see functions for details)       
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %%
%             if nargin <3
%                 ULF = [0 .003];
%                 VLF = [0.003 .04];
%                 LF = [.04 .15];
%                 HF = [0.15 0.4];
%                 limits = [ULF; VLF; LF; HF];
%             end
%             if nargin < 4
%                 plot_on =1;
%             end
            
            Indx_ULF = find( (limits(1,1) <= F) & (F <= limits(1,2)) );
            Indx_VLF = find( (limits(2,1) <= F) & (F <= limits(2,2)) );
            Indx_LF = find( (limits(3,1) <= F) & (F <= limits(3,2)) );
            Indx_HF = find( (limits(4,1) <= F) & (F <= limits(4,2)) );
            space = F(2)-F(1);
            
            ulf = sum(PSD(Indx_ULF)*space) * 1e6; % convert to ms^2
            vlf = sum(PSD(Indx_VLF)*space) * 1e6; % convert to ms^2
            lf = sum(PSD(Indx_LF)*space) * 1e6;   % convert to ms^2
            hf = sum(PSD(Indx_HF)*space) * 1e6;   % convert to ms^2
            
            ttlpwr = sum([ulf vlf lf hf]);
            
            lf_n = lf/ttlpwr; % normalized
            hf_n = hf/ttlpwr;
            lfhf = round(lf_n/hf_n*100)/100; % lf/hf ratio
            
            if plot_on
                %figure
                % plot PSD
                cla(app.PSDRRplot)
                plot(app.PSDRRplot,F,10*log10(PSD),'b','linewidth',2)
                hold(app.PSDRRplot,"on")
                % plot limits on graph for lf and hf
                plot(app.PSDRRplot,[F(Indx_LF(1)) F(Indx_LF(1))],[-80 40],'k:')
                hold(app.PSDRRplot,"on")
                plot(app.PSDRRplot,[F(Indx_LF(end)) F(Indx_LF(end))],[-80 40],'k:')
                hold(app.PSDRRplot,"on")
                plot(app.PSDRRplot,[F(Indx_HF(end)) F(Indx_HF(end))],[-80 40],'k:')
            
                % labelsc
                text(app.PSDRRplot,0.07,30,'LF','Fontsize',10)
                text(app.PSDRRplot,0.25,30,'HF','Fontname','Times New Roman','Fontsize',10)
                %text(0.15, 35, 'Power Spectral Density','Fontname','Times New Roman','Fontsize',10)
                text(app.PSDRRplot,0.3, -60, strcat('LF/HF=',num2str(lfhf)),'Fontname','Times New Roman','Fontsize',10)
                %ylabel('Normalized PSD (db/Hz)','Fontname','Times New Roman','fontsize',10)
                %xlabel('Frequency (Hz)','Fontname','Times New Roman','fontsize',10)
                axis(app.PSDRRplot,[0 .45 -80 40]);
                box(app.PSDRRplot,"off")
            end % end plot

        end % end function
        
        function out = EvalTimeDomainHRVstats(app,NN,tNN,tWin)

            % [NNmean,NNmedian,NNmode,NNvariance,NNskew,NNkurt,SDNN,NNiqr,RMSSD, pnn50
            % btsdet,avgsqi] = EvalTimeDomainHRVstats(NN,tNN,sqi,HRVparams,windows_all)
            %
            %   OVERVIEW:   This function returns time domain HRV metrics calculated on
            %               input NN intervals.
            %
            %   INPUT:      MANDATORY:
            %               NN             : a single row of NN (normal normal) interval 
            %                                data in seconds
            %               tNN            : the time of the rr interval data 
            %                                (seconds)
            %               sqi            : (Optional )Signal Quality Index; Requires 
            %                                a matrix with at least two columns. Column 
            %                                1 should be timestamps of each sqi measure, 
            %                                and Column 2 should be SQI on a scale from 0 to 1.
            %               HRVparams      : struct of settings for hrv_toolbox analysis
            %               tWin           : vector containing the starting time of each
            %                                windows (in seconds) 
            %                
            %   OUTPUT:     
            %               out.NNmean      : mean
            %               out.NNmedian    : median
            %               out.NNmode      : mode
            %               out.NNvariace   : variance
            %               out.NNskew      : skewness
            %               out.NNkurt      : kurtosis
            %               out.SDNN        : standard deviation
            %               out.NNiqr       : intra quartil range
            %               out.RMSSD       : root mean square difference
            %               out.pnn50       : the fraction of consecutive beats that differ by
            %                                 more than a specified time.
            %               out.btsdet      : number of detected beats
            %               out.avgsqi      : average signal quality
            %               out.tdflag      : 2 - Not enough high SQI data
            %                                 3 - Not enough data in the window to analyze
            %                                 5 - Success
            %
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   ORIGINAL SOURCE AND AUTHORS:     
            %       Written by Adriana N. Vest     
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %
            %   10-25-2017 Modified by Giulia Da Poian, convert results in ms and 
            %   removed code for writing results on file
            %
            
            % Verify input arguments
            
%             if nargin< 5
%                 error('no input argument!!!!')
%             end
            %if isempty(sqi) 
                 sqi(:,1) = tNN;
                 sqi(:,2) = ones(length(tNN),1);
            %end


            % Set Defaults
            
            %windowlength = 300;
            windowlength = max(app.t); 
            alpha = 50;
            threshold1 = 0.9;
            threshold2 = .20;
            
            
            % Preallocate arrays (all NaN) before entering the loop
            out.NNmean     = nan(1,length(tWin));
            out.NNmedian   = nan(1,length(tWin));
            out.NNmode     = nan(1,length(tWin));
            out.NNvariance = nan(1,length(tWin));
            out.NNskew     = nan(1,length(tWin));
            out.NNkurt     = nan(1,length(tWin));
            out.NNiqr      = nan(1,length(tWin));
            out.SDNN       = nan(1,length(tWin));
            out.RMSSD      = nan(1,length(tWin));
            out.pnn50      = nan(1,length(tWin));
            out.btsdet     = nan(1,length(tWin));
            out.avgsqi     = nan(1,length(tWin));
            out.tdflag     = nan(1,length(tWin));
            
            %Analyze by Window
            
            % Loop through each window of RR data
            for i_win = 1:length(tWin)
                % Check window for sufficient data
                if ~isnan(tWin(i_win))
                    % Isolate data in this window
                    idx_NN_in_win = find(tNN >= tWin(i_win) & tNN < tWin(i_win) + windowlength);
                    idx_sqi_win = find(sqi(:,1) >= tWin(i_win) & sqi(:,1) < tWin(i_win) + windowlength);
                    
                    sqi_win = sqi(idx_sqi_win,:);
                    t_win = tNN(idx_NN_in_win);
                    nn_win = NN(idx_NN_in_win);
            
                    % Analysis of SQI for the window
                    lowqual_idx = find(sqi_win(:,2) < threshold1);
            
                    % If enough data has an adequate SQI, perform the calculations
                    if numel(lowqual_idx)/length(sqi_win(:,2)) < threshold2
            
                        out.NNmean(i_win) = mean(nn_win.* 1000); % compute and convert to ms
                        out.NNmedian(i_win) = median(nn_win.* 1000); % compute and convert to ms
                        out.NNmode(i_win) = mode(nn_win.* 1000); % compute and convert to ms
                        out.NNvariance(i_win) = var(nn_win.* 1000); % compute and convert to ms^2
                        out.NNskew(i_win) = skewness(nn_win); 
                        out.NNkurt(i_win) = kurtosis(nn_win); 
                        out.NNiqr(i_win) = iqr(nn_win.* 1000); % compute and convert to ms
                        out.SDNN(i_win) = std(nn_win.* 1000); % compute and convert to ms % SDNN should only be done on longer data segments
            
                        % RMSSD
                        out.RMSSD(i_win) = runrmssd(app,nn_win.* 1000); % compute and convert to ms
            
                        % pNN50
                        out.pnn50(i_win) = pnna(app,nn_win, alpha); % 
            
                        out.btsdet(i_win) = length(nn_win);
                        out.avgsqi(i_win) = mean(sqi_win(:,2));
                        
                        out.tdflag(i_win) = 5; % 5 : 'sucess';
                      
                    else
                        % 2: low SQI
                        out.tdflag(i_win) = 2; 
                    end % end of conditional statements that run if SQI is above threshold2
                    
                else
                    % 3: Not enough data in the window to analyze;
                    out.tdflag(i_win) = 3; 
                end % end check for sufficient data
                
            end % end of loop through window

        end % end of function
        
        function output = runrmssd(app,rr)
        % output = runrmssd(times, rr);
        %   OVERVIEW:   calculates the rmssd: the square root of the mean 
        %               of the sum of the squares of the differences.
        %   INPUT:      rr = a single row of rr interval data in seconds
        %               times =  the time indices of the rr interval data (seconds)
        %   OUTPUT:     output = rmssd
        %   DEPENDENCIES
        %   & LIBRARIES:    replaceOutliers.m
        %                   AnnotationConversion.m
        %   REFERENCE:  
        %	REPO:       
        %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
        %   SOURCE:     Adapted from Kaplan Tools
        %               See: http://www.robots.ox.ac.uk/~gari/CODE/HRV/
        %   AUTHORS:    Adriana Nicholson Vest, PhD
        %   COPYRIGHT (C) 2016
        %   LICENSE:    
        %%
        
        dif = diff(rr);
        
        output = sqrt(mean(dif.*dif));

        end
        
        function output = pnna(app,rr, alpha)
            %
            %   output = pnna(rr,alpha)
            %
            %	OVERVIEW:   finds the fraction of consecutive beats that differ by
            %               more than a specified time.
            %               Setting <alpha> to 50 msec will make this equivalent to pnn50
            %               50 msec is the default value of <alpha>
            %               When no arguments are given, the program documents itself%
            %
            %   INPUT:     
            %               rr    - is a vector containing RR intervals in seconds
            %               alpha - is a number which specifies the limit of successive 
            %                       differences in milliseconds (50 ms by default) 
            %   OUTPUT:     
            %               pnna - Probability of intervals greater alpha ms or 
            %                      smaller -alpha ms (e.g., alpha = 50 ms)
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %   SOURCE:     Adapted from Kaplan Tools
            %               See: http://www.robots.ox.ac.uk/~gari/CODE/HRV/
            %	AUTHORS:    Adriana Vest <adriana.vest@gmail.com>
            %	COPYRIGHT (C) 2016 AUTHORS (see above)
            %   LICENSE:    This code (and all others in this repository) are under 
            %               the GNU General Public License v3
            
            % set default parameters
%             if nargin < 2
%                 % assume alpha is in millisecs
%                 alpha = 50;
%             end
            
            dif = diff(rr); 
            
            % pnnalpha is the fraction of differences larger than alpha
            
            output = sum( abs(dif) >= alpha/1000 )/length(dif);


        end
        
        function [SD1, SD2, SD1_SD2_ratio] = EvalPoincareOnWindows(app,rr, t_rr, tWin)
            
            %   OVERVIEW:
            %       Calculates SD1 SD2 and SD1_SD2_ratio features from Poincare plot
            %       for each defined windows 
            %   INPUT
            %       rr           - (seconds) rr intervals
            %       t_rr         - (seconds)  time stamp of rr intervals
            %       HRVparams    - struct of settings for hrv_toolbox analysis
            %       sqi          - Signal Quality Index; Requires a matrix with
            %                      at least two columns. Column 1 should be
            %                      timestamps of each sqi measure, and Column 2
            %                      should be SQI on a scale from 0 to 1.
            %       tWin         - Starting time of each windows to analyze 
            %
            %   OUTPUTS:
            %       SD1           : (ms) standard  deviation  of  projection  of  the   
            %                       PP  on  the line perpendicular to the line of 
            %                       identity (y=-x)
            %       SD2           : (ms) standard deviation of the projection of the PP  
            %                       on the line of identity (y=x)
            %       SD1_SD2_ratio : (ms) SD1/SD2 ratio
            % 
            % 
            %   Written by: Giulia Da Poian <giulia.dap@gmail.com>
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox  
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %
            
            % Make vector a column
            rr = rr(:);
            
%             if nargin < 4
%                 error('no data provided')
%             end
%             if nargin <5 || isempty(sqi)
                sqi(:,1) = t_rr;
                sqi(:,2) = ones(length(t_rr),1);
%             end
            
            %windowlength = 300;
            windowlength = max(app.t);
            SQI_th = 0.9;        % SQI threshold
            WinQuality_th = 0.20; % Low quality windows threshold
            
            % Preallocation (all NaN)
            
            SD1 = ones(length(tWin),1)*NaN;
            SD2 = ones(length(tWin),1)*NaN;
            SD1_SD2_ratio = ones(length(tWin),1)*NaN;
            
            % Run PoincareMetrics by Windows
            % Loop through each window of RR data
            for i_win = 1:length(tWin)
                if ~isnan(tWin(i_win))
                    % Isolate data in this window
                    sqi_win = sqi( sqi(:,1) >= tWin(i_win) & sqi(:,1) < tWin(i_win) + windowlength,:);
                    nn_win = rr( t_rr >= tWin(i_win) & t_rr < tWin(i_win) + windowlength );
                    lowqual_idx = find(sqi_win(:,2) < SQI_th);         % Analysis of SQI for the window
                    % If enough data has an adequate SQI, perform the calculations
                    if numel(lowqual_idx)/length(sqi_win(:,2)) < WinQuality_th
                        [SD1(i_win), SD2(i_win), SD1_SD2_ratio(i_win)] = PoincareMetrics(app,nn_win);
                    end % end of conditional statements run when SQI is adequate
                end % end of check for sufficient data
            end % end of loop through windows


        end % end of function
        
        function [SD1, SD2, SD1_SD2_ratio] = PoincareMetrics(app,RRints)
            %   [SD1, SD2, SD12] = PoincareMetrics(rr)
            %   OVERVIEW:
            %   The Poincaré plot analysis is a geometrical and nonlinear method to 
            %   assess the dynamics of HRV. 
            %   Poincaré plot is generated via plotting each RR interval (RR[n]) 
            %   against the subsequent RR interval (RR[n+1]).
            %   This function allows the user to calculate a number of Poincaré
            %   features, which are detailed below.
            %
            %   INPUTS:    
            %       rr  : Row vector of NN-intervals in seconds
            %     flag  : (Optional) 1: show Poincaré plot, 
            %                        0: do not show Poincaré plot
            %   OUTPUTS:
            %       SD1           : (ms) standard  deviation  of  projection  of  the  
            %                       PP  on  the line perpendicular to the line of 
            %                       identity (y=-x)
            %       SD2           : (ms) standard deviation of the projection of the PP  
            %                       on the line of identity (y=x)
            %       SD1_SD2_ratio : SD1/SD2 ratio
            % 
            % 
            %   Written by: Giulia Da Poian <giulia.dap@gmail.com>
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %%
            
%             if nargin<2
%                 flag = 0; % default do not show plot
%             end
            
            SDSD = std(diff(RRints));
            SDRR = std(RRints);
            SD1 = (1 / sqrt(2)) * SDSD; % measures the width of poincare cloud
            SD2 = sqrt((2 * SDRR^2) - (0.5 * SDSD^2)); % measures the length of the poincare cloud
            
            SD1_SD2_ratio = SD1/SD2;
            
            % Poincare plot  
%             if flag
                ax1 = RRints(1:end-1);
                ax2 = RRints(2:end);
                scatter(app.PoincarePlot,ax1, ax2)
                %xlabel('RR_n (s)')
                %ylabel('RR_n+1 (s)')
%             end
            
            % Convert to ms
            SD1 = SD1 * 1000;
            SD2 = SD2 * 1000;
            
        end    % End function
        
        function [SampEn, ApEn] = EvalEntropyMetrics(app,rr, t_rr, m, r, tWin)

            % [SampEn, ApEn] = EvalEntropyMetrics(rr, t_rr, m, r, HRVparams, tWin, sqi)
            %
            % Overview
            %	Calculates Sample Entropy and Approximate Entropy values of input data.
            %
            % Input
            %   rr          - (seconds) rr intervals
            %   t_rr        - (seconds) time of the rr intervals
            %   m           - pattern length
            %   r           - radius of similarity 
            %   HRVparams   - struct of settings for hrv_toolbox analysis
            %   sqi         - Signal Quality Index; Requires a matrix with at least two 
            %                 columns. Column 1 should be timestamps of each sqi
            %                 measure, and Column 2 should be SQI on a scale from 0 to 1
            %   tWin        - vector containing the starting time of each
            %                                windows (in seconds) 
            % Output
            %   SampEn      - 
            %   ApEn        -
            %
            % Written by Giulia Da Poian <giulia.dap@gmail.com>
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %
            
            
%             if nargin<3
%                 Error('Not enough input arguments!');
%             end
%             if nargin<4 || isempty(HRVparams)
                windowlength = max(app.t);
                %windowlength = length(data);
                SQI_th = 0.9;          % SQI threshold
                WinQuality_th = 0.20;  % Low quality windows threshold
%             else
%                 windowlength = HRVparams.windowlength;
%                 SQI_th = HRVparams.sqi.LowQualityThreshold;        % SQI threshold
%                 WinQuality_th = HRVparams.RejectionThreshold;  % Low quality windows threshold
%             end
            %if nargin<5 || isempty(tWin)
%                 tWin = 0;
            %end
            %if nargin <6 || isempty(sqi)
                sqi(:,1) = t_rr;
                sqi(:,2) = ones(length(t_rr),1);
            %end
            
            % Preallocation (all NaN)
            SampEn = nan(length(tWin),1);
            ApEn = nan(length(tWin),1);
            
            % What Sample Entropy function?
            SampEnType = 'Maxim'; % Initialize default SampEn method 
            if windowlength < 34000; SampEnType = 'Fast'; end     
             
            % Loop through each window of RR data
            for iWin = 1:length(tWin)
                if ~isnan(tWin(iWin))
                    % Isolate data in this window
                    sqi_win = sqi( sqi(:,1) >= tWin(iWin) & sqi(:,1) < tWin(iWin) + windowlength,:);
                    nn_win = rr( t_rr >= tWin(iWin) & t_rr < tWin(iWin) + windowlength );
                    lowqual_idx = find(sqi_win(:,2) < SQI_th);  % Analysis of SQI for the window
                    % If enough data has an adequate SQI, perform the calculations
                    if numel(lowqual_idx)/length(sqi_win(:,2)) < WinQuality_th
                        
                        nn_win = zscore(nn_win);  % normalization of the signal that replace the common 
                                                  % practice of expressing the tolerance as r times the std
                        % 1. Sample Entropy                                  
                        switch SampEnType
                            case 'Fast'
                               SampEn(iWin) = fastSampen(app,nn_win, m, r);
                            otherwise
                               SampEn(iWin) = sampenMaxim(app,nn_win, m, r); 
                        end
                        % 2. Approximate Entropy
                        ApEn(iWin) =  ApproxEntropy(app, nn_win, m, r);
                    end 
                end 
            end     
        end
        
        function s = fastSampen(app,y,m,r)
            % INPUTS
            %  y     num_var x num_samples
            %  m     template length
            %  r     radius
            % OUTPUTS
            %  s     Sample Entropy
            %
            % Written by Shamim Nemati <shamim.nemati@alum.mit.edu>
            %
            % 01-31-2018 : modified by Giulia Da Poian <giulia.dap@gmail.com>, see
            %              comments in the code 
            %              NOTE : Sample entropy quantifies the likelihood that a 
            %              sequence of m consecutive data points that matches another
            %              sequence of the same length (match within a tolerance of r) 
            %              will still match the other sequence when their length is 
            %              increased of one sample (sequences of length m + 1); 
            %              References : 
            %              1. Richman JS, Moorman JR. Physiological time-series 
            %              analysis using approximate entropy and sample entropy. 
            %              American Journal of Physiology-Heart and Circulatory 
            %              Physiology. 2000 Jun 1;278(6):H2039-49.
            %              2. Humeau-Heurtier A. The multiscale entropy 
            %              algorithm and its variants: A review. Entropy. 2015 May 
            %              12;17(5):3110-23.
            % LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            
            % Ensure y is a row vector
            if size(y, 1) > size(y,2)
                y = y';
            end
            
            xx = convert_to_lagged_form(app,y, m)';   % Giulia: replaced m-1 with m to match SampEn definition
            Dxx = pdist(xx,'chebychev');
            
            yy = convert_to_lagged_form(app,y, m+1)'; % Giulia: replaced m with m+1 to match SampEn definition
            Dyy = pdist(yy,'chebychev');
            
            A = mean( Dxx < r ) ;
            B = mean( Dyy < r );
            
            s = -log(B/A);

        end


        function yy = convert_to_lagged_form(app,y, k)
                % Create an observation vector yy(:,t) containing the last k values of y, newest first
                % e.g., k=2, y = (a1 a2 a3)     yy  = a2 a3
                %                (b1 b2 b3)           b2 b2
                %                                     a1 a2
                %                                     b1 b2
                [s, T] = size(y);
                bs = s*ones(1,k);
                yy = zeros(k*s, T-k+1);
                for i=1:k,     yy(block(app,i,bs), :) = y(:, k-i+1:end-i+1); end
        
        end

        function sub = block(app,blocks, block_sizes)
                % BLOCK Return a vector of subscripts corresponding to the specified blocks.
                % sub = block(blocks, block_sizes)
                %
                % e.g., block([2 5], [2 1 2 1 2]) = [3 7 8].
                blocks = blocks(:)';
                block_sizes = block_sizes(:)';
                skip = [0 cumsum(block_sizes)];
                start = skip(blocks)+1;
                fin = start + block_sizes(blocks) - 1;
                sub = [];
                for j=1:length(blocks)
                    sub = [sub start(j):fin(j)];
                end
        
        end
        

        function [entropy, conf95] = sampenMaxim(app,data, m, r)
            % SAMPEN Calculate SampEn value
            %
            % Description:
            %   The function takes a column vector of data and calculates Sample
            %   Entropy value of order m and similarity r.
            %
            % Arguments:
            %   data - column vector with data
            %   m - pattern length
            %   r - similarity criteria (absolute value)
            %
            % Results:
            %   entropy - Sample Entropy value
            %   conf95 - 95% confidence interval
            %
            % Copyright (C) 2011-2013, Maxim Osipov
            %
            % All rights reserved.
            %
            % Redistribution and use in source and binary forms, with or without modification,
            % are permitted provided that the following conditions are met:
            %
            %  - Redistributions of source code must retain the above copyright notice, this
            %    list of conditions and the following disclaimer.
            %  - Redistributions in binary form must reproduce the above copyright notice,
            %    this list of conditions and the following disclaimer in the documentation
            %    and/or other materials provided with the distribution.
            %  - Neither the name of the University of Oxford nor the names of its
            %    contributors may be used to endorse or promote products derived from this
            %    software without specific prior written permission.
            %
            % THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS" AND
            % ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE IMPLIED
            % WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE ARE DISCLAIMED.
            % IN NO EVENT SHALL THE COPYRIGHT HOLDER OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT,
            % INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL DAMAGES (INCLUDING,
            % BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE,
            % DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF
            % LIABILITY, WHETHER IN CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING NEGLIGENCE
            % OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE OF THIS SOFTWARE, EVEN IF ADVISED
            % OF THE POSSIBILITY OF SUCH DAMAGE.
            %
            
                len = length(data);
                % create an array of all m-templates
                tpl_len = len-m+1;
                tpl_data = zeros(tpl_len, m);
                i_tpl = 1;      % index of first template
                n_tpl = 0;      % length of first template series
                for i_data = 1:m
                    n_data = floor((len-i_data+1)/m)*m;
                    i_tpl = i_tpl + n_tpl;
                    n_tpl = floor(n_data/m);
                    tpl_data(i_tpl:(i_tpl+n_tpl-1), :) =...
                        reshape(data(i_data:(i_data+n_data-1)), m, n_tpl)';
                end
                % count template matches, excluding self-matches
                BB = zeros(1, tpl_len);
                tpl_data = repmat(tpl_data, 2, 1);
                % shifting the vector until floor((tpl_len/2)+1 to avoid
                % double count of the same match
                for i_tpl = 2:floor((tpl_len/2)+1)
                    tpl_diff = abs(tpl_data(1:tpl_len, :) -...
                        tpl_data(i_tpl:i_tpl+tpl_len-1, :));
                    BB(i_tpl-1) = sum(sum((tpl_diff < r), 2) == m);
                end
                B = sum(BB)/(len-m);
                % create an array of all m+1 templates
                mp = m+1;
                tpl_len = len-mp+1;
                tpl_data = zeros(tpl_len, mp);
                i_tpl = 1;      % index of first template
                n_tpl = 0;      % length of first template series
                for i_data = 1:mp,
                    n_data = floor((len-i_data+1)/mp)*mp;
                    i_tpl = i_tpl + n_tpl;
                    n_tpl = floor(n_data/mp);
                    tpl_data(i_tpl:(i_tpl+n_tpl-1), :) =...
                        reshape(data(i_data:(i_data+n_data-1)), mp, n_tpl)';
                end
                % count template matches, excluding self-matches
                AA = zeros(1, tpl_len);
                tpl_data = repmat(tpl_data, 2, 1);
                % shifting the vector until floor((tpl_len/2)+1) to avoid
                % double count of the same match
                for i_tpl = 2:floor((tpl_len/2)+1),
                    tpl_diff = abs(tpl_data(1:tpl_len, :) -...
                        tpl_data(i_tpl:i_tpl+tpl_len-1, :));
                    AA(i_tpl-1) = sum(sum((tpl_diff < r), 2) == mp);
                end
                A = sum(AA)/(len-mp);
                
                % calculate value of sample entropy
                if (A<=0) || (B<=0),
                    entropy = -log(1/(m*mp));
                else
                    entropy = -log((A/(len-mp))/(B/(len-m)));
                end
            
                % calculate 95% confidence interval
                EE = AA(1:len-mp)./BB(1:len-mp);
                conf95 = std(EE)*tinv(0.95,length(EE))/sqrt(length(EE));
    end
        
        function ApEn = ApproxEntropy(app, data, m, r)
            % OVERVIEW: This function calculates the approximate entropy for the input
            % vector data
            %
            % INPUTS
            %   data : time-series data
            %   m    : Template length
            %   r    : tolerance (typically 0.2 * std)
            % OUTPUT
            %   ApEn : approximate entropy value
            %
            % Written by Giulia Da Poian <giulia.dap@gmail.com>
            %       Last modified by Camilo Valderrama -- 7/16/2019
            %	REPO:       
            %       https://github.com/cliffordlab/PhysioNet-Cardiovascular-Signal-Toolbox
            %	COPYRIGHT (C) 2016 
            %   LICENSE:    
            %       This software is offered freely and without warranty under 
            %       the GNU (v3 or later) public license. See license file for
            %       more information
            %
            
            N = length(data);
            result = zeros(1,2);
            
            for j = 1:2
                m = m+j-1;
                phi = zeros(1,N-m+1);
                dataMat = zeros(m,N-m+1);
                
                % setting up data matrix
                for i = 1:m
                    dataMat(i,:) = data(i:N-m+i);
                end
                
                % counting similar patterns using distance calculation
                for i = 1:N-m+1
                    
                    if sum( isnan( dataMat(:,i) ) ) == 0
                    
                        tempMat = abs(dataMat - repmat(dataMat(:,i),1,N-m+1));
                        boolMat = any( (tempMat > r),1);
                        phi(i) = sum(~boolMat)/(N-m+1);
                    else
            	    %discarding blocks with nan values
                        phi(i) = nan;
                
                    end
                end
                
                % summing over the counts
                result(j) = log( nanmean( phi) );    
            end
            
            ApEn = result(1) - result(2) ;

    end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %CargarECGButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            app.Activ = 0;
        end

        % Button pushed function: CargarECGButton
        function CargarECGButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.mat');  %open a mat file
                File = fullfile(path, file);
                app.ECG = cell2mat(struct2cell(load(File)));
                app.ECG_original = app.ECG;
                CrearRR(app);
                app.Activ = 0;
            catch
                if maxmax(size(app.ECG))<1
                    msgbox('No se ha cargado ninguna señal');
                    %warning('No se ha cargado ninguna señal');
                    app.Activ = 0;
                else
                    app.Activ = 1;
                end
            end
        end

        % Button pushed function: GrficarButton
        function GrficarButtonPushed(app, event)
            if max(size(app.ECG))>1
                Fs = app.FrecuenciademuestreoHzEditField.Value;
                tf = 1/Fs*length(app.ECG);
                app.t =  linspace(0,tf,length(app.ECG)); % [seg]
                app.TiempomnimoSlider.Limits = [0,max(app.t)-3];
                app.TiempomnimoSlider.Value = 0;
                app.TiempomximoSlider.Limits = [1,max(app.t)];
                app.TiempomximoSlider.Value = max(app.t);
                app.FrecuenciamximaSlider.Limits = [0,Fs/2];
                app.FrecuenciamximaSlider.Value = Fs/2;
                Graficar(app)
                PSD(app)
                PSD2(app)
                app.Activ = 1;
            end
        end

        % Value changing function: TiempomnimoSlider
        function TiempomnimoSliderValueChanging(app, event)
            if app.Activ == 1
                changingValue = event.Value;
                app.TiempomximoSlider.Limits = [changingValue+1,max(app.t)];
                Graficar(app)
            end
        end

        % Value changing function: TiempomximoSlider
        function TiempomximoSliderValueChanging(app, event)
            %changingValue = event.Value;
            %Graficar(app)
        end

        % Value changed function: TiempomnimoSlider
        function TiempomnimoSliderValueChanged(app, event)
            %value = app.TiempomnimoSlider.Value;
            if app.Activ == 1
                Graficar(app)
            end
        end

        % Value changed function: TiempomximoSlider
        function TiempomximoSliderValueChanged(app, event)
            %value = app.TiempomximoSlider.Value;
            if app.Activ == 1
                Graficar(app)
            end
        end

        % Value changed function: FrecuenciamximaSlider
        function FrecuenciamximaSliderValueChanged(app, event)
            %value = app.FrecuenciamximaSlider.Value;
            if app.Activ == 1
                PSD(app)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.Activ == 1
                app.ECG = app.ECG_original;
                CrearRR(app);
                GrficarButtonPushed(app, event);
            end
        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            if app.Activ == 1
                Fs = app.FrecuenciademuestreoHzEditField.Value; % Frecuencia de muestreo
                if app.FiltradodelasealListBox.Value == '1'
                    N = 2000;   % Orden filtro
                    Fc = 0.5;   % Hz, frecuencia de corte
                    filt1 = fir1(N, Fc/(Fs/2), 'high');
                    app.ECG = filtfilt(filt1,1,app.ECG);
                elseif app.FiltradodelasealListBox.Value == '2'
                    Fc = 50;     % Hz, Frecuencia de corte
                    Q = 45;      % Factor de calidad
                    wo = Fc/(Fs/2);  
                    bw = wo/Q;
                    [b,a] = iirnotch(wo,bw);
                    app.ECG = filtfilt(b,a,app.ECG);
                elseif app.FiltradodelasealListBox.Value == '3'
                    Fc = 60;     % Hz, Frecuencia de corte
                    Q = 45;      % Factor de calidad
                    wo = Fc/(Fs/2);  
                    bw = wo/Q;
                    [b,a] = iirnotch(wo,bw);
                    app.ECG = filtfilt(b,a,app.ECG);
                 elseif app.FiltradodelasealListBox.Value == '4'
                    N = 72;   % Orden filtro
                    Fc = 10;   % Hz, frecuencia de corte
                    filt1 = fir1(N, Fc/(Fs/2), 'high');
                    app.ECG = filtfilt(filt1,1,app.ECG); 
                  elseif app.FiltradodelasealListBox.Value == '5'
                    N = 72;   % Orden filtro
                    Fc = 20;   % Hz, frecuencia de corte
                    filt1 = fir1(N, Fc/(Fs/2), 'low');
                    app.ECG = filtfilt(filt1,1,app.ECG);
                   elseif app.FiltradodelasealListBox.Value == '6'
                    N = 72;   % Orden filtro
                    Fc = 30;   % Hz, frecuencia de corte
                    filt1 = fir1(N, Fc/(Fs/2), 'low');
                    app.ECG = filtfilt(filt1,1,app.ECG); 
                end
                Graficar(app)
                PSD(app)
                CrearRR(app);
                PSD2(app)
            end
        end

        % Value changed function: MtododePSDKnob
        function MtododePSDKnobValueChanged(app, event)
            %value = app.MtododePSDKnob.Value;
            if app.Activ == 1
                PSD(app)
                CrearRR(app);
                PSD2(app);
            end
        end

        % Cell edit callback: TablaFrecuencia
        function TablaFrecuenciaCellEdit(app, event)

        end

        % Value changed function: QRSCheckBox
        function QRSCheckBoxValueChanged(app, event)
            %value = app.QRSCheckBox.Value;
            if app.Activ == 1
                Graficar(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PSDRRplot = uiaxes(app.UIFigure);
            title(app.PSDRRplot, 'Densidad de potencia espectral del intervalo RR')
            xlabel(app.PSDRRplot, 'Frecuencia (Hz)')
            ylabel(app.PSDRRplot, 'Potencia/frecuencia (dB/Hz)')
            zlabel(app.PSDRRplot, 'Z')
            app.IntervalosRR = uiaxes(app.UIFigure);
            title(app.IntervalosRR, 'Intervalos RR')
            xlabel(app.IntervalosRR, 'Tiempo (seg)')
            ylabel(app.IntervalosRR, 'Amplitud (seg)')
            zlabel(app.IntervalosRR, 'Z')
            app.PSDplot = uiaxes(app.UIFigure);
            title(app.PSDplot, 'Densidad de potencia espectral del ECG')
            xlabel(app.PSDplot, 'Frecuencia (Hz)')
            ylabel(app.PSDplot, 'Potencia/frecuencia (dB/Hz)')
            zlabel(app.PSDplot, 'Z')
            app.ECGTiempo = uiaxes(app.UIFigure);
            title(app.ECGTiempo, 'ECG')
            xlabel(app.ECGTiempo, 'Tiempo (seg)')
            ylabel(app.ECGTiempo, 'Amplitud')
            zlabel(app.ECGTiempo, 'Z')
            app.PoincarePlot = uiaxes(app.UIFigure);
            title(app.PoincarePlot, 'Diagrama de Poincaré')
            xlabel(app.PoincarePlot, 'RR n (s)')
            ylabel(app.PoincarePlot, 'RR (n+1) (s)')
            zlabel(app.PoincarePlot, 'Z')
            app.FiltrarButton = uibutton(app.UIFigure, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrficarButton = uibutton(app.UIFigure, 'push');
            app.GrficarButton.ButtonPushedFcn = createCallbackFcn(app, @GrficarButtonPushed, true);
            app.GrficarButton.Text = 'Gráficar';
            app.CargarECGButton = uibutton(app.UIFigure, 'push');
            app.CargarECGButton.ButtonPushedFcn = createCallbackFcn(app, @CargarECGButtonPushed, true);
            app.CargarECGButton.Text = 'Cargar ECG';
            app.FrecuenciademuestreoHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciademuestreoHzEditField.Value = 360;
            app.FrecuenciademuestreoHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoHzEditFieldLabel.Text = 'Frecuencia de muestreo (Hz)';
            app.TiempomnimoSlider = uislider(app.UIFigure);
            app.TiempomnimoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempomnimoSliderValueChanged, true);
            app.TiempomnimoSlider.ValueChangingFcn = createCallbackFcn(app, @TiempomnimoSliderValueChanging, true);
            app.TiempomnimoSliderLabel = uilabel(app.UIFigure);
            app.TiempomnimoSliderLabel.Text = 'Tiempo mínimo';
            app.TiempomximoSlider = uislider(app.UIFigure);
            app.TiempomximoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempomximoSliderValueChanged, true);
            app.TiempomximoSlider.ValueChangingFcn = createCallbackFcn(app, @TiempomximoSliderValueChanging, true);
            app.TiempomximoSliderLabel = uilabel(app.UIFigure);
            app.TiempomximoSliderLabel.Text = 'Tiempo máximo';
            app.FrecuenciamximaSlider = uislider(app.UIFigure);
            app.FrecuenciamximaSlider.ValueChangedFcn = createCallbackFcn(app, @FrecuenciamximaSliderValueChanged, true);
            app.FrecuenciamximaSliderLabel = uilabel(app.UIFigure);
            app.FrecuenciamximaSliderLabel.Text = 'Frecuencia máxima';
            app.FiltradodelasealListBox = uilistbox(app.UIFigure);
            app.FiltradodelasealListBox.Items = {'Eliminar desp. de línea base', 'Filtro Notch 50Hz', 'Filtro Notch 60Hz', 'Filtro Pasa Alto 10Hz', 'Filtro Pasa Bajo 20Hz', 'Filtro Pasa Bajo 30Hz'};
            app.FiltradodelasealListBox.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.FiltradodelasealListBox.Value = '1';
            app.FiltradodelasealListBoxLabel = uilabel(app.UIFigure);
            app.FiltradodelasealListBoxLabel.Text = 'Filtrado de la señal';
            app.MtododePSDKnob = uiknob(app.UIFigure, 'discrete');
            app.MtododePSDKnob.Items = {'FFT', 'Burg', 'Welch'};
            app.MtododePSDKnob.ItemsData = {'fft', 'burg', 'welch'};
            app.MtododePSDKnob.ValueChangedFcn = createCallbackFcn(app, @MtododePSDKnobValueChanged, true);
            app.MtododePSDKnob.Value = 'fft';
            app.MtododePSDKnobLabel = uilabel(app.UIFigure);
            app.MtododePSDKnobLabel.Text = 'Método de PSD';
            app.TablaFrecuencia = uitable(app.UIFigure);
            app.TablaFrecuencia.ColumnName = {'Medida'; 'Pot Absoluta m2'; 'Pot relativa %'};
            app.TablaFrecuencia.RowName = {};
            app.TablaFrecuencia.CellEditCallback = createCallbackFcn(app, @TablaFrecuenciaCellEdit, true);
            app.AnlisisespectraldelaVFCLabel = uilabel(app.UIFigure);
            app.AnlisisespectraldelaVFCLabel.Text = 'Análisis espectral de la VFC';
            app.TablaTiempo = uitable(app.UIFigure);
            app.TablaTiempo.ColumnName = {'Medida'; 'Valor'};
            app.TablaTiempo.RowName = {};
            app.AnlisiseneltiempodelaVFCLabel = uilabel(app.UIFigure);
            app.AnlisiseneltiempodelaVFCLabel.Text = 'Análisis en el tiempo de la VFC';
            app.TablaNoLineal = uitable(app.UIFigure);
            app.TablaNoLineal.ColumnName = {'Medida'; 'Valor'};
            app.TablaNoLineal.RowName = {};
            app.AnlisisnolinealdelaVFCLabel = uilabel(app.UIFigure);
            app.AnlisisnolinealdelaVFCLabel.Text = 'Análisis no lineal de la VFC';
            app.QRSCheckBox = uicheckbox(app.UIFigure);
            app.QRSCheckBox.ValueChangedFcn = createCallbackFcn(app, @QRSCheckBoxValueChanged, true);
            app.QRSCheckBox.Text = 'QRS';
            app.httpsiopscienceioporgarticle10108813616579aae021Label = uilabel(app.UIFigure);
            app.httpsiopscienceioporgarticle10108813616579aae021Label.Text = {'Vest AN, Da Poian G, Li Q, Liu C, Nemati S, Shah AJ, Clifford GD. An open source benchmarked toolbox for cardiovascular waveform and interval analysis. Physiol Meas. 2018 Oct 11;39(10):105004. doi: 10.1088/1361-6579/aae021. PMID: 30199376; PMCID: PMC6442742.'; ''; 'Goldberger, A., Amaral, L., Glass, L., Hausdorff, J., Ivanov, P. C., Mark, R., ... & Stanley, H. E. (2000). PhysioBank, PhysioToolkit, and PhysioNet: Components of a new research resource for complex physiologic signals. Circulation [Online]. 101 (23), pp. e215–e220.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tarea5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end